classdef Microcavity_Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Optics_Simulations_figure  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Microcavity                matlab.ui.container.Tab
        graph_MCavity              matlab.ui.control.UIAxes
        ParametersPanel            matlab.ui.container.Panel
        GeneralPanel               matlab.ui.container.Panel
        idegLabel                  matlab.ui.control.Label
        M_thetai                   matlab.ui.control.NumericEditField
        EiVmLabel                  matlab.ui.control.Label
        M_Ei                       matlab.ui.control.NumericEditField
        niEditFieldLabel           matlab.ui.control.Label
        M_ni                       matlab.ui.control.NumericEditField
        nfEditFieldLabel           matlab.ui.control.Label
        M_nf                       matlab.ui.control.NumericEditField
        cnmLabel                   matlab.ui.control.Label
        M_LambdaC                  matlab.ui.control.NumericEditField
        EidegLabel                 matlab.ui.control.Label
        M_thetaEi                  matlab.ui.control.NumericEditField
        DBR1Panel                  matlab.ui.container.Panel
        n2EditFieldLabel           matlab.ui.control.Label
        M_D1_n2                    matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        M_D1_N                     matlab.ui.control.NumericEditField
        d1nmLabel                  matlab.ui.control.Label
        M_D1_d1                    matlab.ui.control.NumericEditField
        d2nmLabel                  matlab.ui.control.Label
        M_D1_d2                    matlab.ui.control.NumericEditField
        n1EditFieldLabel           matlab.ui.control.Label
        M_D1_n1                    matlab.ui.control.NumericEditField
        DBR2Panel                  matlab.ui.container.Panel
        n2EditField_2Label         matlab.ui.control.Label
        M_D2_n2                    matlab.ui.control.NumericEditField
        NEditField_2Label          matlab.ui.control.Label
        M_D2_N                     matlab.ui.control.NumericEditField
        d1nmLabel_2                matlab.ui.control.Label
        M_D2_d1                    matlab.ui.control.NumericEditField
        d2nmLabel_2                matlab.ui.control.Label
        M_D2_d2                    matlab.ui.control.NumericEditField
        n1EditField_2Label         matlab.ui.control.Label
        M_D2_n1                    matlab.ui.control.NumericEditField
        CavityLayerPanel           matlab.ui.container.Panel
        dcnmLabel                  matlab.ui.control.Label
        M_dc                       matlab.ui.control.NumericEditField
        ncEditFieldLabel           matlab.ui.control.Label
        M_nc                       matlab.ui.control.NumericEditField
        M_UpdateButton             matlab.ui.control.Button
        M_state                    matlab.ui.control.StateButton
        PlotDropDownLabel          matlab.ui.control.Label
        M_PlotDropDown             matlab.ui.control.DropDown
        DBR                        matlab.ui.container.Tab
        ParametersPanel_2          matlab.ui.container.Panel
        GeneralPanel_3             matlab.ui.container.Panel
        idegLabel_3                matlab.ui.control.Label
        D_thetai                   matlab.ui.control.NumericEditField
        EiVmLabel_3                matlab.ui.control.Label
        D_Ei                       matlab.ui.control.NumericEditField
        niEditFieldLabel_3         matlab.ui.control.Label
        D_ni                       matlab.ui.control.NumericEditField
        nfEditFieldLabel_3         matlab.ui.control.Label
        D_nf                       matlab.ui.control.NumericEditField
        cnmLabel_3                 matlab.ui.control.Label
        D_LambdaC                  matlab.ui.control.NumericEditField
        EidegLabel_3               matlab.ui.control.Label
        D_thetaEi                  matlab.ui.control.NumericEditField
        DBRPanel                   matlab.ui.container.Panel
        n2EditFieldLabel_2         matlab.ui.control.Label
        D_n2                       matlab.ui.control.NumericEditField
        NEditFieldLabel_2          matlab.ui.control.Label
        D_N                        matlab.ui.control.NumericEditField
        d1nmLabel_3                matlab.ui.control.Label
        D_d1                       matlab.ui.control.NumericEditField
        d2nmLabel_3                matlab.ui.control.Label
        D_d2                       matlab.ui.control.NumericEditField
        n1EditFieldLabel_2         matlab.ui.control.Label
        D_n1                       matlab.ui.control.NumericEditField
        D_UpdateButton             matlab.ui.control.Button
        D_state                    matlab.ui.control.StateButton
        PlotDropDown_2Label        matlab.ui.control.Label
        D_PlotDropDown             matlab.ui.control.DropDown
        graph_DBR                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        
    end
    
    methods (Access =public)
        
        
    end
    

    methods (Access = private)

        % Button pushed function: M_UpdateButton
        function M_UpdateButtonPushed(app, event)
            choice = app.M_PlotDropDown.Value;
            switch choice
                case 'R vs λ'
                    Microcavity_Reflectivity(app);
                case 'U vs k(parallel)'
                    Uvskparallel(app);
                case 'LambdaC vs thetai'
                    LambdaCvsthetai(app);
                case 'E vs x'
                    app.M_state.Text='Stop';
                    app.M_state.Value=1;
                    M_field_profile(app);
            end
        end

        % Button pushed function: D_UpdateButton
        function D_UpdateButtonPushed(app, event)
            choice = app.D_PlotDropDown.Value;
            switch choice
                case 'R vs λ'
                    DBR_Reflectivity(app);
                case 'E vs x'
                    app.D_state.Text='Stop';
                    app.D_state.Value=1;
                    DBR_field_profile(app);
                case 'Experimental vs Simulation'
                    DBR_Exp_vs_Sim(app);
            end
        end

        % Value changed function: M_state
        function M_stateValueChanged(app, event)
            value = app.M_state.Value;
            choice = app.M_PlotDropDown.Value;
            if choice == 'E vs x'
                if value==0
                    app.M_state.Text='Play';
                else
                    app.M_state.Text='Stop';
                    M_field_profile(app);
                end
            else
                app.M_state.Value=1;
                app.M_state.Text='Stop';
            end
            
        end

        % Value changed function: D_state
        function D_stateValueChanged(app, event)
            value = app.D_state.Value;
            choice = app.D_PlotDropDown.Value;
            if choice == 'E vs x'
                if value==0
                    app.D_state.Text='Play';
                else
                    app.D_state.Text='Stop';
                    DBR_field_profile(app);
                end
            else
                app.M_state.Value=1;
                app.M_state.Text='Stop';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Optics_Simulations_figure = uifigure;
            app.Optics_Simulations_figure.Name = 'Optics Simulations';
            app.TabGroup = uitabgroup(app.Optics_Simulations_figure);
            app.Microcavity = uitab(app.TabGroup);
            app.Microcavity.Title = 'Microcavity Simulation';
            app.graph_MCavity = uiaxes(app.Microcavity);
            title(app.graph_MCavity, 'Title')
            xlabel(app.graph_MCavity, 'X')
            ylabel(app.graph_MCavity, 'Y')
            app.ParametersPanel = uipanel(app.Microcavity);
            app.ParametersPanel.Title = 'Parameters';
            app.GeneralPanel = uipanel(app.ParametersPanel);
            app.GeneralPanel.Title = 'General';
            app.idegLabel = uilabel(app.GeneralPanel);
            app.idegLabel.Text = 'θi(deg)';
            app.M_thetai = uieditfield(app.GeneralPanel, 'numeric');
            app.M_thetai.Limits = [0 90];
            app.M_thetai.Value = 30;
            app.EiVmLabel = uilabel(app.GeneralPanel);
            app.EiVmLabel.Text = 'Ei(V/m)';
            app.M_Ei = uieditfield(app.GeneralPanel, 'numeric');
            app.M_Ei.Limits = [0 Inf];
            app.M_Ei.Value = 5;
            app.niEditFieldLabel = uilabel(app.GeneralPanel);
            app.niEditFieldLabel.Text = 'ni';
            app.M_ni = uieditfield(app.GeneralPanel, 'numeric');
            app.M_ni.Limits = [1 Inf];
            app.M_ni.Value = 1;
            app.nfEditFieldLabel = uilabel(app.GeneralPanel);
            app.nfEditFieldLabel.Text = 'nf';
            app.M_nf = uieditfield(app.GeneralPanel, 'numeric');
            app.M_nf.Limits = [1 Inf];
            app.M_nf.Value = 1;
            app.cnmLabel = uilabel(app.GeneralPanel);
            app.cnmLabel.Text = 'λc(nm)';
            app.M_LambdaC = uieditfield(app.GeneralPanel, 'numeric');
            app.M_LambdaC.Limits = [0 Inf];
            app.M_LambdaC.Value = 660;
            app.EidegLabel = uilabel(app.GeneralPanel);
            app.EidegLabel.Text = 'θEi(deg)';
            app.M_thetaEi = uieditfield(app.GeneralPanel, 'numeric');
            app.M_thetaEi.Limits = [0 90];
            app.M_thetaEi.Value = 45;
            app.DBR1Panel = uipanel(app.ParametersPanel);
            app.DBR1Panel.Title = 'DBR1';
            app.n2EditFieldLabel = uilabel(app.DBR1Panel);
            app.n2EditFieldLabel.Text = 'n2';
            app.M_D1_n2 = uieditfield(app.DBR1Panel, 'numeric');
            app.M_D1_n2.Limits = [1 Inf];
            app.M_D1_n2.Value = 1.46;
            app.NEditFieldLabel = uilabel(app.DBR1Panel);
            app.NEditFieldLabel.Text = 'N';
            app.M_D1_N = uieditfield(app.DBR1Panel, 'numeric');
            app.M_D1_N.LowerLimitInclusive = 'off';
            app.M_D1_N.Limits = [0 Inf];
            app.M_D1_N.RoundFractionalValues = 'on';
            app.M_D1_N.Value = 21;
            app.d1nmLabel = uilabel(app.DBR1Panel);
            app.d1nmLabel.Text = 'd1(nm)';
            app.M_D1_d1 = uieditfield(app.DBR1Panel, 'numeric');
            app.M_D1_d1.Editable = 'off';
            app.d2nmLabel = uilabel(app.DBR1Panel);
            app.d2nmLabel.Text = 'd2(nm)';
            app.M_D1_d2 = uieditfield(app.DBR1Panel, 'numeric');
            app.M_D1_d2.Editable = 'off';
            app.n1EditFieldLabel = uilabel(app.DBR1Panel);
            app.n1EditFieldLabel.Text = 'n1';
            app.M_D1_n1 = uieditfield(app.DBR1Panel, 'numeric');
            app.M_D1_n1.Limits = [1 Inf];
            app.M_D1_n1.Value = 2.02;
            app.DBR2Panel = uipanel(app.ParametersPanel);
            app.DBR2Panel.Title = 'DBR2';
            app.n2EditField_2Label = uilabel(app.DBR2Panel);
            app.n2EditField_2Label.Text = 'n2';
            app.M_D2_n2 = uieditfield(app.DBR2Panel, 'numeric');
            app.M_D2_n2.Limits = [1 Inf];
            app.M_D2_n2.Value = 1.46;
            app.NEditField_2Label = uilabel(app.DBR2Panel);
            app.NEditField_2Label.Text = 'N';
            app.M_D2_N = uieditfield(app.DBR2Panel, 'numeric');
            app.M_D2_N.LowerLimitInclusive = 'off';
            app.M_D2_N.Limits = [0 Inf];
            app.M_D2_N.Value = 21;
            app.d1nmLabel_2 = uilabel(app.DBR2Panel);
            app.d1nmLabel_2.Text = 'd1(nm)';
            app.M_D2_d1 = uieditfield(app.DBR2Panel, 'numeric');
            app.M_D2_d1.Editable = 'off';
            app.d2nmLabel_2 = uilabel(app.DBR2Panel);
            app.d2nmLabel_2.Text = 'd2(nm)';
            app.M_D2_d2 = uieditfield(app.DBR2Panel, 'numeric');
            app.M_D2_d2.Editable = 'off';
            app.n1EditField_2Label = uilabel(app.DBR2Panel);
            app.n1EditField_2Label.Text = 'n1';
            app.M_D2_n1 = uieditfield(app.DBR2Panel, 'numeric');
            app.M_D2_n1.Limits = [1 Inf];
            app.M_D2_n1.Value = 2.02;
            app.CavityLayerPanel = uipanel(app.ParametersPanel);
            app.CavityLayerPanel.Title = 'Cavity Layer';
            app.dcnmLabel = uilabel(app.CavityLayerPanel);
            app.dcnmLabel.Text = 'dc(nm)';
            app.M_dc = uieditfield(app.CavityLayerPanel, 'numeric');
            app.M_dc.Editable = 'off';
            app.ncEditFieldLabel = uilabel(app.CavityLayerPanel);
            app.ncEditFieldLabel.Text = 'nc';
            app.M_nc = uieditfield(app.CavityLayerPanel, 'numeric');
            app.M_nc.Limits = [1 Inf];
            app.M_nc.Value = 1.46;
            app.M_UpdateButton = uibutton(app.ParametersPanel, 'push');
            app.M_UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @M_UpdateButtonPushed, true);
            app.M_UpdateButton.Text = 'Update';
            app.M_state = uibutton(app.ParametersPanel, 'state');
            app.M_state.ValueChangedFcn = createCallbackFcn(app, @M_stateValueChanged, true);
            app.M_state.Text = 'Stop';
            app.M_state.Value = true;
            app.PlotDropDownLabel = uilabel(app.Microcavity);
            app.PlotDropDownLabel.Text = 'Plot';
            app.M_PlotDropDown = uidropdown(app.Microcavity);
            app.M_PlotDropDown.Items = {'R vs λ', 'U vs k(parallel)', 'LambdaC vs thetai', 'E vs x'};
            app.M_PlotDropDown.Value = 'R vs λ';
            app.DBR = uitab(app.TabGroup);
            app.DBR.Title = 'DBR Simulation';
            app.ParametersPanel_2 = uipanel(app.DBR);
            app.ParametersPanel_2.Title = 'Parameters';
            app.GeneralPanel_3 = uipanel(app.ParametersPanel_2);
            app.GeneralPanel_3.Title = 'General';
            app.idegLabel_3 = uilabel(app.GeneralPanel_3);
            app.idegLabel_3.Text = 'θi(deg)';
            app.D_thetai = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_thetai.Limits = [0 90];
            app.D_thetai.Value = 30;
            app.EiVmLabel_3 = uilabel(app.GeneralPanel_3);
            app.EiVmLabel_3.Text = 'Ei(V/m)';
            app.D_Ei = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_Ei.Limits = [0 Inf];
            app.D_Ei.Value = 5;
            app.niEditFieldLabel_3 = uilabel(app.GeneralPanel_3);
            app.niEditFieldLabel_3.Text = 'ni';
            app.D_ni = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_ni.Limits = [1 Inf];
            app.D_ni.Value = 1;
            app.nfEditFieldLabel_3 = uilabel(app.GeneralPanel_3);
            app.nfEditFieldLabel_3.Text = 'nf';
            app.D_nf = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_nf.Limits = [1 Inf];
            app.D_nf.Value = 1;
            app.cnmLabel_3 = uilabel(app.GeneralPanel_3);
            app.cnmLabel_3.Text = 'λc(nm)';
            app.D_LambdaC = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_LambdaC.Limits = [0 Inf];
            app.D_LambdaC.Value = 660;
            app.EidegLabel_3 = uilabel(app.GeneralPanel_3);
            app.EidegLabel_3.Text = 'θEi(deg)';
            app.D_thetaEi = uieditfield(app.GeneralPanel_3, 'numeric');
            app.D_thetaEi.Limits = [0 90];
            app.D_thetaEi.Value = 45;
            app.DBRPanel = uipanel(app.ParametersPanel_2);
            app.DBRPanel.Title = 'DBR';
            app.n2EditFieldLabel_2 = uilabel(app.DBRPanel);
            app.n2EditFieldLabel_2.Text = 'n2';
            app.D_n2 = uieditfield(app.DBRPanel, 'numeric');
            app.D_n2.Limits = [1 Inf];
            app.D_n2.Value = 1.46;
            app.NEditFieldLabel_2 = uilabel(app.DBRPanel);
            app.NEditFieldLabel_2.Text = 'N';
            app.D_N = uieditfield(app.DBRPanel, 'numeric');
            app.D_N.LowerLimitInclusive = 'off';
            app.D_N.Limits = [0 Inf];
            app.D_N.RoundFractionalValues = 'on';
            app.D_N.Value = 21;
            app.d1nmLabel_3 = uilabel(app.DBRPanel);
            app.d1nmLabel_3.Text = 'd1(nm)';
            app.D_d1 = uieditfield(app.DBRPanel, 'numeric');
            app.D_d1.Editable = 'off';
            app.d2nmLabel_3 = uilabel(app.DBRPanel);
            app.d2nmLabel_3.Text = 'd2(nm)';
            app.D_d2 = uieditfield(app.DBRPanel, 'numeric');
            app.D_d2.Editable = 'off';
            app.n1EditFieldLabel_2 = uilabel(app.DBRPanel);
            app.n1EditFieldLabel_2.Text = 'n1';
            app.D_n1 = uieditfield(app.DBRPanel, 'numeric');
            app.D_n1.Limits = [1 Inf];
            app.D_n1.Value = 2.02;
            app.D_UpdateButton = uibutton(app.ParametersPanel_2, 'push');
            app.D_UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @D_UpdateButtonPushed, true);
            app.D_UpdateButton.Text = 'Update';
            app.D_state = uibutton(app.ParametersPanel_2, 'state');
            app.D_state.ValueChangedFcn = createCallbackFcn(app, @D_stateValueChanged, true);
            app.D_state.Text = 'Stop';
            app.D_state.Value = true;
            app.PlotDropDown_2Label = uilabel(app.DBR);
            app.PlotDropDown_2Label.Text = 'Plot';
            app.D_PlotDropDown = uidropdown(app.DBR);
            app.D_PlotDropDown.Items = {'R vs λ', 'E vs x', 'Experimental vs Simulation'};
            app.D_PlotDropDown.Value = 'R vs λ';
            app.graph_DBR = uiaxes(app.DBR);
            title(app.graph_DBR, 'Title')
            xlabel(app.graph_DBR, 'X')
            ylabel(app.graph_DBR, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Microcavity_Simulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Optics_Simulations_figure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Optics_Simulations_figure)
        end
    end
end